classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        Location1Button    matlab.ui.control.Button
        LocationsLabel     matlab.ui.control.Label
        CenterPanel        matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        YearSlider         matlab.ui.control.Slider
        YearSliderLabel    matlab.ui.control.Label
        Image              matlab.ui.control.Image
        LocationnameLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        Tool1Button        matlab.ui.control.Button
        AnalysisLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        LocationButtons matlab.ui.control.Button = matlab.ui.control.Button.empty;
        ImageCollections containers.Map = containers.Map('KeyType','char','ValueType','any');
    end
    
    methods (Access = private)
        function onLocationButtonPushed(app, src, ~)
            location = src.Text;
            app.LocationnameLabel.Text = location;
            

            locationPath = fullfile(pwd, "Datasets", location);
            jpgFiles   = dir(fullfile(locationPath, "*.jpg"))

            years   = [];
            files = strings(0);
            for k = 1:numel(jpgFiles)
                fname = jpgFiles(k).name
                yearTok = regexp(fname, '(19|20)\d{2}', 'match', 'once');
                if ~isempty(yearTok)
                    years(end+1)   = str2double(yearTok);
                    files(end+1) = fullfile(jpgFiles(k).folder, fname);
                end
            end

            [years, ord] = sort(years);
            files = files(ord);

            info = struct('years', years, 'files', files);
            app.ImageCollections(location) = info;

            app.YearSlider.Limits     = [info.years(1) info.years(end)];
            currentYear = round(app.YearSlider.Value)
            [~, idx] = min(abs(info.years - currentYear))
            app.YearSlider.Value = double(info.years(idx));
            app.YearSlider.MajorTicks = info.years;
            app.YearSlider.MinorTicks = [];

            app.Image.ImageSource = info.files(idx)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function generateLocationButtons(app)
            datasetPath = fullfile(pwd, "Datasets");
            if ~isfolder(datasetPath)
                warning('Folder "%s" not found. No location buttons created.', datasetPath);
                return;
            end
            subDirectories = dir(datasetPath);
            subDirectories = subDirectories([subDirectories.isdir]);
            subDirectories = subDirectories(~ismember({subDirectories.name}, {'.', '..'}));
            
            subDirectoryCount = numel(subDirectories);

            buttonTemplate   = app.Location1Button.Position;
            buttonHeight     = buttonTemplate(4);
            buttonWidth      = buttonTemplate(3);
            buttonX          = buttonTemplate(1);
            buttonYStart     = buttonTemplate(2);
            buttonGap        = 10;
    
            app.LocationButtons = matlab.ui.control.Button.empty;

            for k = 1:subDirectoryCount
                y = buttonYStart - (k-1) * (buttonHeight + buttonGap);
                x = buttonX;
                locationButton = uibutton(app.LeftPanel, 'push');
                locationButton.Position        = [x, y, buttonWidth, buttonHeight];
                locationButton.BackgroundColor = app.Location1Button.BackgroundColor;
                locationButton.FontSize        = app.Location1Button.FontSize;
                locationButton.FontColor       = app.Location1Button.FontColor;
                locationButton.Text            = subDirectories(k).name;
                locationButton.ButtonPushedFcn = @(src, evt) onLocationButtonPushed(app, src, evt);

                app.LocationButtons(end+1) = locationButton;
            end
        end

        % Value changing function: YearSlider
        function YearSliderValueChanging(app, event)
            location = app.LocationnameLabel.Text;
            if ~isKey(app.ImageCollections, location)
                return;
            end

            info = app.ImageCollections(location);

            value = floor(event.Value);
            idx = find(info.years <= value, 1, 'last');
            if isempty(idx)
                idx = 1;
            end
            year   = info.years(idx);

            app.YearSlider.Value      = year;
            app.Image.ImageSource = info.files(idx);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LocationsLabel = uilabel(app.LeftPanel);
            app.LocationsLabel.Text = 'Locations';
            app.Location1Button = uibutton(app.LeftPanel, 'push');
            app.Location1Button.Text = 'Location 1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.LocationnameLabel = uilabel(app.GridLayout2);
            app.LocationnameLabel.Text = 'Location name';
            app.Image = uiimage(app.GridLayout2);
            app.YearSliderLabel = uilabel(app.GridLayout2);
            app.YearSliderLabel.Text = 'Year';
            app.YearSlider = uislider(app.GridLayout2);
            app.YearSlider.Limits = [1990 2025];
            app.YearSlider.ValueChangingFcn = createCallbackFcn(app, @YearSliderValueChanging, true);
            app.YearSlider.Step = 0.01;
            app.YearSlider.Value = 1990;
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalysisLabel = uilabel(app.RightPanel);
            app.AnalysisLabel.Text = 'Analysis';
            app.Tool1Button = uibutton(app.RightPanel, 'push');
            app.Tool1Button.Text = 'Tool 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @generateLocationButtons)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end